classdef GraphicsEQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EquilizerSSProjectUIFigure  matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        MainTab                     matlab.ui.container.Tab
        EqualizedAudioPlayerPanel   matlab.ui.container.Panel
        PlayAudioButton_2           matlab.ui.control.Button
        SaveAudioPanel              matlab.ui.container.Panel
        SaveButton                  matlab.ui.control.Button
        OriginalAudioPlayerPanel    matlab.ui.container.Panel
        ResumeButton                matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        StopAudioButton             matlab.ui.control.Button
        PlayAudioButton             matlab.ui.control.Button
        TakeAudioPanel              matlab.ui.container.Panel
        RecordAudioButton           matlab.ui.control.Button
        LoadAudioButton             matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
        freqfft                     matlab.ui.control.UIAxes
        time                        matlab.ui.control.UIAxes
        EQTab                       matlab.ui.container.Tab
        PlotEqualizeButton          matlab.ui.control.Button
        kHzLabel_3                  matlab.ui.control.Label
        kHzLabel_2                  matlab.ui.control.Label
        kHzLabel                    matlab.ui.control.Label
        HzLabel_6                   matlab.ui.control.Label
        HzLabel_5                   matlab.ui.control.Label
        HzLabel_4                   matlab.ui.control.Label
        HzLabel_3                   matlab.ui.control.Label
        HzLabel_2                   matlab.ui.control.Label
        HzLabel                     matlab.ui.control.Label
        InitEqualizerButton         matlab.ui.control.Button
        HighFrequncySlider          matlab.ui.control.Slider
        HighFrequncySliderLabel     matlab.ui.control.Label
        Slider_7                    matlab.ui.control.Slider
        Slider_6                    matlab.ui.control.Slider
        MidFrequncySlider           matlab.ui.control.Slider
        MidFrequncySliderLabel      matlab.ui.control.Label
        Slider_5                    matlab.ui.control.Slider
        Slider_4                    matlab.ui.control.Slider
        Slider_3                    matlab.ui.control.Slider
        Slider_2                    matlab.ui.control.Slider
        LowFrequncySlider           matlab.ui.control.Slider
        Image                       matlab.ui.control.Image
        LowFrequncySliderLabel      matlab.ui.control.Label
    end


    properties (Access = public)
        filename
        loadedAudio;recordedAudio
        loadedAudio_FS = 0;
        pathname
        player;loadbuttonFunc =0;equalizer
        lfs1 = 0 ;   mfs1 = 0 ;   hfs1 = 0 ;
        lfs2 = 0 ;   mfs2 = 0 ;   hfs2 = 0 ;
        lfs3 = 0 ;   mfs3 = 0 ;   hfs3 = 0 ;
        playereq ; reader;
        

    end
    
    methods (Access = private)
        
        function results = Checkstatus(app)
            results = app.stopPlayer;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            app.loadbuttonFunc = 1;
            [app.filename, app.pathname] = uigetfile({'*.wav';'*.*'},'File Selector');
            [app.loadedAudio,Fs]=audioread(app.filename);
            app.loadedAudio_FS = Fs;
            app.player = audioplayer(app.loadedAudio, Fs);

            L = length(app.loadedAudio);
            Toriginal = 0: 1/Fs: (L - 1)/Fs;
            plot(app.time,Toriginal,app.loadedAudio,'b');
            df = Fs/L;
            Fvec = -Fs/2:df:Fs/2 - df;
            Xw=fftshift(fft(app.loadedAudio))/length(fft(app.loadedAudio));
            plot(app.freqfft,Fvec,abs(Xw),'b');
            clc

        end

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)
            recObj = audiorecorder;
            recordblocking(recObj, 10);
            app.recordedAudio = getaudiodata(recObj); % this can be used as param for filters
            app.player = audioplayer(app.recordedAudio, 8000);
            Fs = 8000;
            L = length(app.recordedAudio);
            Toriginal = 0: 1/Fs: (L - 1)/Fs;

            plot(app.time,Toriginal,app.recordedAudio);
            df = Fs/L;
            Fvec = -Fs/2:df:Fs/2 - df;
            Xw=fftshift(fft(app.recordedAudio))/length(fft(app.recordedAudio));
            plot(app.freqfft,Fvec,abs(Xw));
            clc
        end

        % Callback function
        function StopRecButtonPushed(app, event)

        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            stop(app.player)
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            play(app.player)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.loadbuttonFunc == 1
                audiowrite('savedAudio.waav',app.loadedAudio,app.loadedAudio_FS);
            else
                audiowrite('savedAudio.wav',app.recordedAudio,8000);
            end
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            resume(app.player)
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(app.player)
        end

        % Value changed function: LowFrequncySlider
        function LowFrequncySliderValueChanged(app, event)
            app.lfs1 = app.LowFrequncySlider.Value;
            %             N=2;
            %             Wn1 = 250/(app.loadedAudio_FS/2);[num1,den1] = butter(N,Wn1);
            %             h2=fir1(100,250/app.loadedAudio_FS,'low'); y2=filter(h2,1,app.recordedAudio);
            %             Y2 = y2*value;
            %             fvtooll(num1,den1);
        end

        % Button pushed function: InitEqualizerButton
        function InitEqualizerButtonPushed(app, event)
            frameLength = 512;
            if app.loadbuttonFunc == 1
                app.reader = dsp.AudioFileReader(app.filename,'SamplesPerFrame',frameLength);
                app.playereq = audioDeviceWriter('SampleRate',app.reader.SampleRate);
            else
                audiowrite('temp.wav',app.recordedAudio,8000);
                app.reader = dsp.AudioFileReader('temp.wav','SamplesPerFrame',frameLength);
                app.playereq = audioDeviceWriter('SampleRate',app.reader.SampleRate);
            end
            app.equalizer = graphicEQ( ...
                'Bandwidth','1 octave', ...
                'Structure','Cascade', ...
                'SampleRate',app.reader.SampleRate);
            app.equalizer.Gains = [app.lfs1,app.lfs2,app.lfs3,app.mfs1,app.mfs2,0,app.mfs3,app.hfs1,app.hfs2,app.hfs3];
            visualize(app.equalizer)
            clc
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.lfs2 = app.Slider_2.Value;

        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            app.lfs3= app.Slider_3.Value;

        end

        % Value changed function: MidFrequncySlider
        function MidFrequncySliderValueChanged(app, event)
            app.mfs1= app.MidFrequncySlider.Value;

        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            app.mfs2= app.Slider_4.Value;

        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            app.mfs3= app.Slider_5.Value;

        end

        % Value changed function: HighFrequncySlider
        function HighFrequncySliderValueChanged(app, event)
            app.hfs1 = app.HighFrequncySlider.Value;

        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            app.hfs2= app.Slider_6.Value;

        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            app.hfs3 = app.Slider_7.Value;

        end

        % Button pushed function: PlotEqualizeButton
        function PlotEqualizeButtonPushed(app, event)
            app.equalizer.Gains = [app.lfs1,app.lfs2,app.lfs3,app.mfs1,app.mfs2,0,app.mfs3,app.hfs1,app.hfs2,app.hfs3];
            visualize(app.equalizer)
        end

        % Button pushed function: PlayAudioButton_2
        function PlayAudioButton_2Pushed(app, event)
            while ~isDone(app.reader) %&& app.stopPlayer

%                 if ~app.pausePlayer
                    x = app.reader();
%  app.x = getaudiodata(app.reader);
%                     play(app.x);
                    app.playereq (x);
%                 end
%                 Checkstatus(app);
%                 if app.resumePlayer
%                     app.pausePlayer = false;
%                 end
%                 if app.stopPlayer
%                     break;
%                 end
% 
%                 if app.pausePlayer
%                     continue;
%                 end
            end
%             play(app.x);
  
    %             clear app.reader app.playereq
    %             release(app.reader)
    %             release(app.playereq)
        end

        % Callback function
        function PauseButton_2Pushed(app, event)
        
        end

        % Callback function
        function ResumeButton_2Pushed(app, event)
 
        end

        % Callback function
        function StopAudioButton_2Pushed(app, event)
            app.stopPlayer = true;
            stop(app.x);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EquilizerSSProjectUIFigure = uifigure('Visible', 'off');
            app.EquilizerSSProjectUIFigure.Name = 'Equilizer : SS Project';
            app.TabGroup = uitabgroup(app.EquilizerSSProjectUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.time = uiaxes(app.MainTab);
            title(app.time, 'Time Domain')
            xlabel(app.time, 'Time(sec)')
            ylabel(app.time, 'Amplitude')
            zlabel(app.time, 'Z')
            app.freqfft = uiaxes(app.MainTab);
            title(app.freqfft, 'Frequncy Domain')
            xlabel(app.freqfft, 'Frequncy(Hz)')
            ylabel(app.freqfft, 'Amplitude')
            zlabel(app.freqfft, 'Z')
            app.Image2 = uiimage(app.MainTab);
            app.Image2.ImageSource = 'BG.jpg';
            app.TakeAudioPanel = uipanel(app.MainTab);
            app.TakeAudioPanel.Title = 'Take Audio ';
            app.LoadAudioButton = uibutton(app.TakeAudioPanel, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio';
            app.RecordAudioButton = uibutton(app.TakeAudioPanel, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.OriginalAudioPlayerPanel = uipanel(app.MainTab);
            app.OriginalAudioPlayerPanel.Title = 'Original Audio Player';
            app.PlayAudioButton = uibutton(app.OriginalAudioPlayerPanel, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.StopAudioButton = uibutton(app.OriginalAudioPlayerPanel, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = {'Stop Audio'; ''};
            app.PauseButton = uibutton(app.OriginalAudioPlayerPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.OriginalAudioPlayerPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.SaveAudioPanel = uipanel(app.MainTab);
            app.SaveAudioPanel.Title = 'Save Audio';
            app.SaveButton = uibutton(app.SaveAudioPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = {'Save'; ''};
            app.EqualizedAudioPlayerPanel = uipanel(app.MainTab);
            app.EqualizedAudioPlayerPanel.Title = 'Equalized Audio Player';
            app.PlayAudioButton_2 = uibutton(app.EqualizedAudioPlayerPanel, 'push');
            app.PlayAudioButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButton_2Pushed, true);
            app.PlayAudioButton_2.Text = 'Play Audio';
            app.EQTab = uitab(app.TabGroup);
            app.EQTab.Title = 'EQ';
            app.LowFrequncySliderLabel = uilabel(app.EQTab);
            app.LowFrequncySliderLabel.Text = 'Low Frequncy';
            app.Image = uiimage(app.EQTab);
            app.Image.ImageSource = 'BG.jpg';
            app.LowFrequncySlider = uislider(app.EQTab);
            app.LowFrequncySlider.Limits = [-10 10];
            app.LowFrequncySlider.MajorTicks = 0;
            app.LowFrequncySlider.ValueChangedFcn = createCallbackFcn(app, @LowFrequncySliderValueChanged, true);
            app.LowFrequncySlider.MinorTicks = [];
            app.LowFrequncySlider.Value = 1;
            app.Slider_2 = uislider(app.EQTab);
            app.Slider_2.Limits = [-20 20];
            app.Slider_2.MajorTicks = 0;
            app.Slider_2.MajorTickLabels = {'0'};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_3 = uislider(app.EQTab);
            app.Slider_3.Limits = [-20 20];
            app.Slider_3.MajorTicks = 0;
            app.Slider_3.MajorTickLabels = {'0'};
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_4 = uislider(app.EQTab);
            app.Slider_4.Limits = [-20 20];
            app.Slider_4.MajorTicks = 0;
            app.Slider_4.MajorTickLabels = {'0'};
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.MinorTicks = [];
            app.Slider_5 = uislider(app.EQTab);
            app.Slider_5.Limits = [-20 20];
            app.Slider_5.MajorTicks = 0;
            app.Slider_5.MajorTickLabels = {'0'};
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.MinorTicks = [];
            app.MidFrequncySliderLabel = uilabel(app.EQTab);
            app.MidFrequncySliderLabel.Text = 'Mid Frequncy ';
            app.MidFrequncySlider = uislider(app.EQTab);
            app.MidFrequncySlider.Limits = [-20 20];
            app.MidFrequncySlider.MajorTicks = 0;
            app.MidFrequncySlider.ValueChangedFcn = createCallbackFcn(app, @MidFrequncySliderValueChanged, true);
            app.MidFrequncySlider.MinorTicks = [];
            app.Slider_6 = uislider(app.EQTab);
            app.Slider_6.Limits = [-20 20];
            app.Slider_6.MajorTicks = 0;
            app.Slider_6.MajorTickLabels = {'0'};
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.MinorTicks = [];
            app.Slider_7 = uislider(app.EQTab);
            app.Slider_7.Limits = [-20 20];
            app.Slider_7.MajorTicks = 0;
            app.Slider_7.MajorTickLabels = {'0'};
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.MinorTicks = [];
            app.HighFrequncySliderLabel = uilabel(app.EQTab);
            app.HighFrequncySliderLabel.Text = 'High Frequncy ';
            app.HighFrequncySlider = uislider(app.EQTab);
            app.HighFrequncySlider.Limits = [-20 20];
            app.HighFrequncySlider.MajorTicks = 0;
            app.HighFrequncySlider.ValueChangedFcn = createCallbackFcn(app, @HighFrequncySliderValueChanged, true);
            app.HighFrequncySlider.MinorTicks = [];
            app.InitEqualizerButton = uibutton(app.EQTab, 'push');
            app.InitEqualizerButton.ButtonPushedFcn = createCallbackFcn(app, @InitEqualizerButtonPushed, true);
            app.InitEqualizerButton.Text = 'Init. Equalizer';
            app.HzLabel = uilabel(app.EQTab);
            app.HzLabel.Text = '31.5 Hz';
            app.HzLabel_2 = uilabel(app.EQTab);
            app.HzLabel_2.Text = '63 Hz';
            app.HzLabel_3 = uilabel(app.EQTab);
            app.HzLabel_3.Text = '125 Hz';
            app.HzLabel_4 = uilabel(app.EQTab);
            app.HzLabel_4.Text = '250 Hz';
            app.HzLabel_5 = uilabel(app.EQTab);
            app.HzLabel_5.Text = '500 Hz';
            app.HzLabel_6 = uilabel(app.EQTab);
            app.HzLabel_6.Text = '2000 Hz';
            app.kHzLabel = uilabel(app.EQTab);
            app.kHzLabel.Text = '4k Hz';
            app.kHzLabel_2 = uilabel(app.EQTab);
            app.kHzLabel_2.Text = '8k Hz';
            app.kHzLabel_3 = uilabel(app.EQTab);
            app.kHzLabel_3.Text = '16k Hz';
            app.PlotEqualizeButton = uibutton(app.EQTab, 'push');
            app.PlotEqualizeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEqualizeButtonPushed, true);
            app.PlotEqualizeButton.Text = 'Plot & Equalize';
            % Show the figure after all components are created
            app.EquilizerSSProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphicsEQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EquilizerSSProjectUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EquilizerSSProjectUIFigure)
        end
    end
end